classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Menu_3          matlab.ui.container.Menu
        Menu_4          matlab.ui.container.Menu
        Menu_5          matlab.ui.container.Menu
        ChirpMenu       matlab.ui.container.Menu
        Menu_6          matlab.ui.container.Menu
        Menu_7          matlab.ui.container.Menu
        Menu_8          matlab.ui.container.Menu
        Menu_9          matlab.ui.container.Menu
        Menu_11         matlab.ui.container.Menu
        Menu_10         matlab.ui.container.Menu
        Menu_12         matlab.ui.container.Menu
        Menu_15         matlab.ui.container.Menu
        Menu_16         matlab.ui.container.Menu
        Menu_17         matlab.ui.container.Menu
        Menu_18         matlab.ui.container.Menu
        Menu_19         matlab.ui.container.Menu
        Menu_26         matlab.ui.container.Menu
        Menu_27         matlab.ui.container.Menu
        Menu_28         matlab.ui.container.Menu
        Menu_29         matlab.ui.container.Menu
        Menu_30         matlab.ui.container.Menu
        Menu_31         matlab.ui.container.Menu
        RMSMenu         matlab.ui.container.Menu
        Menu_32         matlab.ui.container.Menu
        Menu_20         matlab.ui.container.Menu
        Menu_21         matlab.ui.container.Menu
        Menu_23         matlab.ui.container.Menu
        Menu_22         matlab.ui.container.Menu
        Menu_24         matlab.ui.container.Menu
        Menu_25         matlab.ui.container.Menu
        EditField_6     matlab.ui.control.EditField
        Label_6         matlab.ui.control.Label
        Panel_2         matlab.ui.container.Panel
        EditField_5     matlab.ui.control.EditField
        Label_5         matlab.ui.control.Label
        EditField_4     matlab.ui.control.EditField
        Label_4         matlab.ui.control.Label
        EditField_3     matlab.ui.control.EditField
        Label_3         matlab.ui.control.Label
        EditField_2     matlab.ui.control.EditField
        Label_2         matlab.ui.control.Label
        Button_6        matlab.ui.control.Button
        Panel           matlab.ui.container.Panel
        CheckBox_9      matlab.ui.control.CheckBox
        CheckBox_8      matlab.ui.control.CheckBox
        CheckBox_7      matlab.ui.control.CheckBox
        CheckBox_6      matlab.ui.control.CheckBox
        CheckBox_5      matlab.ui.control.CheckBox
        CheckBox_4      matlab.ui.control.CheckBox
        ChirpCheckBox   matlab.ui.control.CheckBox
        CheckBox_2      matlab.ui.control.CheckBox
        CheckBox        matlab.ui.control.CheckBox
        Button_5        matlab.ui.control.Button
        Button_4        matlab.ui.control.Button
        Button_3        matlab.ui.control.Button
        EditField       matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        Label           matlab.ui.control.Label
        MATLABLabel     matlab.ui.control.Label
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        recordingData
        sampleRate
    end
    
    methods (Access = private)

        
    function processSignal(app, operation)
    % 确保录音数据存在
    if isempty(app.recordingData) || size(app.recordingData, 2) < 2
        app.EditField.Value = '请先录音!';
        return;
    end
    
    % 获取信号选择状态
    selectedSignals = {};
    
    if app.CheckBox.Value
        selectedSignals{end+1} = 'left';
    end
    if app.CheckBox_2.Value
        selectedSignals{end+1} = 'right';
    end
    if app.ChirpCheckBox.Value
        selectedSignals{end+1} = 'Chirp';
    end
    if app.CheckBox_4.Value
        selectedSignals{end+1} = 'Sine';
    end
    if app.CheckBox_5.Value
        selectedSignals{end+1} = 'Step';
    end
    if app.CheckBox_6.Value
        selectedSignals{end+1} = 'Square';
    end
    if app.CheckBox_7.Value
        selectedSignals{end+1} = 'sampling';
    end
    if app.CheckBox_8.Value
        selectedSignals{end+1} = 'Impulse';
    end
    if app.CheckBox_9.Value
        selectedSignals{end+1} = 'Complexexponential';
    end
    
    % 确保选择了一个或两个信号
    if numel(selectedSignals) < 1 || numel(selectedSignals) > 2
        app.EditField.Value = '请选择一个或两个信号进行运算!';
        return;
    end
    
    if any(strcmp(operation, {'放大', '缩小'})) && numel(selectedSignals) ~= 1
        app.EditField.Value = ['错误: "', operation, '" 操作只能选择一个信号!'];
        return;
    elseif any(strcmp(operation, {'加法', '减法', '乘法', '卷积', '相关'})) && numel(selectedSignals) ~= 2
        app.EditField.Value = ['错误: "', operation, '" 操作需要选择两个信号!'];
        return;
    end
    
    % 获取录音数据和采样率
    Fs = app.sampleRate;
    tmedia = (0:length(app.recordingData)-1) / Fs;
    
    % 定义信号
    signals = struct();
    signals.('left') = app.recordingData(:, 1); % 左声道
    signals.('right') = app.recordingData(:, 2); % 右声道
    t = linspace(0, 4, 1000);  % 时间范围 [0, 4]，1000 个点
    signals.('Sine') = sin(2*pi*t);         % 正弦信号
    signals.('Square') = square(2*pi*t);    % 方波信号
    signals.('Chirp') = chirp(t, 1, max(t), 5); % Chirp 信号
    signals.('Step') = (t >= mean(t));        % 阶跃信号 (将跳变点放在中间)

    center = 2;  % 中心点位置
    % 生成 sinc 函数，中心在 t=2
    signals.('sampling') = sinc(10 * (t - center));

    % 冲激信号，0 在坐标轴中间
    impulse = zeros(size(t)); 
    impulse(round(length(t)/2)) = 1;  % 在中心位置放 1
    signals.('Impulse') = impulse;

    % 复指数信号
    tau = 0.2;  % 时间常数
    f = 5;  % 频率
    signals.('Complexexponential') = exp(-t / tau) .* exp(1j*2*pi*f*t);  % 复指数衰减信号
    
    % 获取用户选择的信号数据
    try
        signal1 = signals.(selectedSignals{1});
        if numel(selectedSignals) == 2
            signal2 = signals.(selectedSignals{2});
        end
    catch
        app.EditField.Value = '所选信号无效!';
        return;
    end

    % 执行信号处理
    try
        switch operation
            case '放大'
                processedSignal = signal1 * 2;
                channelName = '放大后的信号';
            case '缩小'
                processedSignal = signal1 / 2;
                channelName = '缩小后的信号';
            case '加法'
                processedSignal = signal1 + signal2;
                channelName = '信号相加';
            case '减法'
                processedSignal = signal1 - signal2;
                channelName = '信号相减';
            case '乘法'
                processedSignal = signal1 .* signal2;
                channelName = '信号相乘';
            case '卷积'
                processedSignal = conv(signal1, signal2);
                channelName = '信号卷积';
                t = (0:length(processedSignal)-1) / 1000; % 调整时间轴
            case '相关'
                [processedSignal, lag] = xcorr(signal1, signal2);
                channelName = '信号相关';
                t = lag / 1000; % 计算时延
            otherwise
                app.EditField.Value = '未知操作!';
                return;
        end
    catch ME
        % 捕获异常并显示错误信息
        app.EditField.Value = ['不能进行此运算: ', ME.message];
        return;
    end

    % 确保 `processedSignal` 是实数
    if ~isreal(processedSignal)
        processedSignal = real(processedSignal);
    end

    % 清除之前的图像
    cla(app.UIAxes_2);
    hold(app.UIAxes_2, 'on');
    
    % 绘制处理后的信号
    plot(app.UIAxes_2, t, processedSignal, 'b');
    xlabel(app.UIAxes_2, '时间 (秒)');
    ylabel(app.UIAxes_2, '振幅');
    title(app.UIAxes_2, ['处理后的信号: ', channelName]);
    grid(app.UIAxes_2, 'on');
    % 调整 Y 轴，防止超出范围
    ylim(app.UIAxes_2, [min(processedSignal), max(processedSignal)]);
    
    % 强制刷新 UI
    drawnow;
    
    % 更新状态信息
    app.EditField.Value = ['信号处理完成: ', operation, ' 信号: ', strjoin(selectedSignals, ' 和 ')];
end


%自设计信号特征
function selfmadefunc(app,thing)
 % 确保录音数据存在
    if isempty(app.recordingData) || size(app.recordingData, 2) < 2
        app.EditField.Value = '请先录音!';
        return;
    end
    
    % 获取信号选择状态
    selectedSignals = {};
    
    if app.CheckBox.Value
        selectedSignals{end+1} = 'left';
    end
    if app.CheckBox_2.Value
        selectedSignals{end+1} = 'right';
    end
    if app.ChirpCheckBox.Value
        selectedSignals{end+1} = 'Chirp';
    end
    if app.CheckBox_4.Value
        selectedSignals{end+1} = 'Sine';
    end
    if app.CheckBox_5.Value
        selectedSignals{end+1} = 'Step';
    end
    if app.CheckBox_6.Value
        selectedSignals{end+1} = 'Square';
    end
    if app.CheckBox_7.Value
        selectedSignals{end+1} = 'sampling';
    end
    if app.CheckBox_8.Value
        selectedSignals{end+1} = 'Impulse';
    end
    if app.CheckBox_9.Value
        selectedSignals{end+1} = 'Complexexponential';
    end
    
    % 确保选择了一个或两个信号
    if numel(selectedSignals) ~= 1
        app.EditField.Value = '请选择一个信号查看它的信号特征!';
        return;
    end

    % 获取录音数据和采样率
    %Fs = app.sampleRate;
    %tmedia = (0:length(app.recordingData)-1) / Fs;
    
    % 定义信号
    signals = struct();
    signals.('left') = app.recordingData(:, 1); % 左声道
    signals.('right') = app.recordingData(:, 2); % 右声道
    t = linspace(0, 4, 1000);  % 时间范围 [0, 4]，1000 个点
    signals.('Sine') = sin(2*pi*t);         % 正弦信号
    signals.('Square') = square(2*pi*t);    % 方波信号
    signals.('Chirp') = chirp(t, 1, max(t), 5); % Chirp 信号
    signals.('Step') = (t >= mean(t));        % 阶跃信号 (将跳变点放在中间)

    center = 2;  % 中心点位置
    % 生成 sinc 函数，中心在 t=2
    signals.('sampling') = sinc(10 * (t - center));

    % 冲激信号，0 在坐标轴中间
    impulse = zeros(size(t)); 
    impulse(round(length(t)/2)) = 1;  % 在中心位置放 1
    signals.('Impulse') = impulse;

    % 复指数信号
    tau = 0.2;  % 时间常数
    f = 5;  % 频率
    signals.('Complexexponential') = exp(-t / tau) .* exp(1j*2*pi*f*t);  % 复指数衰减信号

    %给相应选择赋值为不同信号函数
    signal = signals.(selectedSignals{1});

    % 计算所选信号的特征
    N = length(signal);  % 信号长度
    switch thing
        case '周期'
            signal = double(signal);
            %内置算法(只适用于规则波形)
            minPeakDistance = 5; % 设定最小峰值间隔
            [~, locs] = findpeaks(signal, 'MinPeakDistance', minPeakDistance); 
            if length(locs) > 1
                T1 = mean(diff(locs)); % 计算相邻峰值间距的均值
            else
                T1 = '无法计算周期'
            end
            result2 = sprintf('%.4f',T1);

            %自己算法：
            peaks = [];
            minPeakDistance = 5; % 设定最小峰值间隔
            for i = 2:N-1
                if signal(i) > signal(i-1) && signal(i) > signal(i+1)
                    if isempty(peaks) || (i - peaks(end) > minPeakDistance)
                        peaks = [peaks, i]; % 记录峰值索引
                    end
                end
            end
        if length(peaks) > 1
            T = sum(diff(peaks)) / (length(peaks) - 1);
            result1 = sprintf('%.4f', T);
        else
            result1 = '无法计算周期';
        end

        case '峰值'
            %内置算法：
            P_max1 = max(signal);
            %自己算法（轮询比较）
            P_max = signal(1);
            for i = 2:N
                if signal(i) > P_max
                    P_max = signal(i);
                end
            end
            result1 = sprintf('%.4f', P_max);
            result2 = sprintf('%.4f', P_max1);

        case '峰峰值'
            %内置算法：
            P_pp1 = peak2peak(signal);
            %自己算法：
            P_max = signal(1);
            P_min = signal(1);
            for i = 2:N
                if signal(i) > P_max
                    P_max = signal(i);
                end
                if signal(i) < P_min
                    P_min = signal(i);
                end
            end
            P_pp = P_max - P_min;
            result1 = sprintf('%.4f', P_pp);
            result2 = sprintf('%.4f', P_pp1);

        case '均值'
            %内置函数
            mu1=mean(signal);
            %自己的函数（轮询相加）
            sum_val = 0;
            for i = 1:N
                sum_val = sum_val + signal(i);
            end
            mu = sum_val / N;
            result1 = sprintf('%.4f', mu);
            result2 = sprintf('%.4f', mu1);

        case '均方值'
            %内置函数
            ms1 = meansqr(signal);
            %自己的函数（定义法）
            sum_sq = 0;
            for i = 1:N
                sum_sq = sum_sq + signal(i)^2;
            end
            ms = sum_sq / N;
            result1 = sprintf('%.4f', ms);
            result2 = sprintf('%.4f', ms1);

        case 'RMS(均方根)'
            %内置函数
            rms_value1 = rms(signal);
            %自己的函数（定义法）
            sum_sq = 0;
            for i = 1:N
                sum_sq = sum_sq + signal(i)^2;
            end
            rms_value = sqrt(sum_sq / N);
            result1 = sprintf('%.4f', rms_value);
            result2 = sprintf('%.4f', rms_value1);

        case '方差'
            %内置函数
            variance1 = var(signal,1);
            %自己的函数（定义法）
            sum_val = 0;
            for i = 1:N
                sum_val = sum_val + signal(i);
            end
            mu = sum_val / N;  % 计算均值

            sum_diff_sq = 0;
            for i = 1:N
                sum_diff_sq = sum_diff_sq + (signal(i) - mu)^2;
            end
            variance = sum_diff_sq / N; % 使用无偏估计 (N 而不是 N-1)

            result1 = sprintf('%.4f', variance);
            result2 = sprintf('%.4f', variance1);

        otherwise
            result1 = '无效特征!';
    end

    % 显示结果到 EditField
    app.EditField.Value = ['内置算法：' , result2 , ' 。 ' , '自己算法：',result1];
    app.EditField_6.Value = [thing, '计算完成'];
       
end


%%%%%%%%%%%%%%%%%%%频谱分析%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
function analyzeSpectrum(app, channel)
    % 确保录音数据存在
    if isempty(app.recordingData)
        app.EditField.Value = '请先录音!';
        return;
    end

    % 获取录音数据和采样率
    Recording = app.recordingData;
    Fs = app.sampleRate;

    % 选择对应的声道进行频谱分析
    switch channel
        case '左声道'
            signal = Recording(:,1);   % 选择左声道
            channelName = '左声道频谱';
        case '右声道'
            signal = Recording(:,2);   % 选择右声道
            channelName = '右声道频谱';
        otherwise
            app.EditField.Value = '请选择有效的声道!';
            return;
    end

    % 计算频谱
    N = length(signal);          % 信号的长度
    Y = fft(signal);             % 对信号进行FFT
    f = (0:N-1)*(Fs/N);          % 频率轴
    magnitude = abs(Y);          % 计算幅度谱

    % 清除之前的图像
    cla(app.UIAxes_2);

    % 绘制频谱
    plot(app.UIAxes_2, f(1:N/2), magnitude(1:N/2), 'b'); % 只绘制正频率部分
    xlabel(app.UIAxes_2, '频率 (Hz)');
    ylabel(app.UIAxes_2, '幅度');
    title(app.UIAxes_2, ['频谱分析: ', channelName]);
    grid(app.UIAxes_2, 'on');
    
    % 更新状态信息
    app.EditField.Value = ['频谱分析完成: ', channelName];
end


function applyFilter(app, channel)
    % 确保录音数据存在
    if isempty(app.recordingData)
        app.EditField.Value = '请先录音!';
        return;
    end

    % 获取录音数据和采样率
    Recording = app.recordingData;
    Fs = app.sampleRate;

    % 分离左右声道
    leftChannel = Recording(:,1);
    rightChannel = Recording(:,2);

    % 设计滤波器参数
    filterOrder = 6;          % 滤波器阶数
    cutoffFreq = 1000;        % 截止频率 (Hz)
    Wn = cutoffFreq / (Fs/2); % 归一化截止频率
    [b, a] = butter(filterOrder, Wn, 'low'); % 设计低通滤波器

    % 选择声道并应用滤波
    switch channel
        case '左声道'
            signal = leftChannel;
            filteredSignal = filter(b, a, signal); % 应用滤波器
            channelName = '左声道滤波';
        case '右声道'
            signal = rightChannel;
            filteredSignal = filter(b, a, signal); % 应用滤波器
            channelName = '右声道滤波';
        otherwise
            app.EditField.Value = '请选择有效的声道!';
            return;
    end

    % 生成时间轴
    t = (0:length(signal)-1) / Fs;

    % 清除之前的图像
    cla(app.UIAxes_2);

    % 绘制滤波后的信号
    plot(app.UIAxes_2, t, filteredSignal, 'b');
    xlabel(app.UIAxes_2, '时间 (秒)');
    ylabel(app.UIAxes_2, '振幅');
    title(app.UIAxes_2, ['滤波后的音频信号: ', channelName]);
   

    % 更新状态信息
    app.EditField.Value = ['滤波完成: ', channelName];
end

        
      function generateSignal(app, signalType)
    Fs = 1000;  % 采样频率 (Hz)
    T = 1;      % 信号时长 (秒)
    t = 0:1/Fs:T;  % 时间向量

    % 生成不同类型的信号
    switch signalType
        case '正弦信号'
            f = 5; % 频率 5 Hz
            y = sin(2 * pi * f * t);  % 正弦波
            
        case '方波信号'
            f = 5; % 频率 5 Hz
            y = square(2 * pi * f * t);  % 方波
            
        case 'Chirp信号'
            f0 = 5;  % 起始频率 5 Hz
            f1 = 50; % 结束频率 50 Hz
            y = chirp(t, f0, T, f1);  % 线性调频信号
            
        case '阶跃信号'
            y = double(t >= 0.5);  % 0.5s 之后变为 1
            
        case '抽样信号'
            y = sinc(10 * (t - 0.5));  % sinc 函数 (中心在 t=0.5)

        case '冲激信号'
            y = zeros(size(t));
            y(abs(t - 0.5) < 1/Fs) = 1;  % 在 t=0.5 处生成单位冲激信号

        case '复指数信号'
            tau = 0.2;  % 时间常数
            f = 10;  % 频率
            y = exp(-t / tau) .* cos(2 * pi * f * t);  % 复指数衰减信号

        otherwise
            y = zeros(size(t)); % 默认情况下显示空信号
    end

    % 清除之前的图像
    cla(app.UIAxes_2);

    % 绘制信号
    plot(app.UIAxes_2, t, y, 'b', 'LineWidth', 1.5);
    xlabel(app.UIAxes_2, '时间 (秒)');
    ylabel(app.UIAxes_2, '幅度');
    title(app.UIAxes_2, ['信号波形: ', signalType]);
    grid(app.UIAxes_2, 'on');

    % 更新状态信息
    app.EditField.Value = ['信号已生成: ', signalType];
end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function MenuSelected(app, event)
    Fs = 44100;               % Sampling frequency
    duration = 4;             % Duration of the recording
    nBits = 16;               % Bit depth
    nChannels = 2;            % Number of channels
    recObj = audiorecorder(Fs, nBits, nChannels); 
   
    % Inform the user that recording has started
    app.EditField.Value = '录音开始!';
    recordblocking(recObj, duration);  % Record the audio for the specified duration
    app.EditField.Value = '录音结束!'; 
    
    % Retrieve the recorded data
    Recording = getaudiodata(recObj);
    
    % Store the recording and sample rate in the app properties
    app.recordingData = Recording;
    app.sampleRate = Fs;  % 存储采样率
    %测试
    %app.EditField.Value = sprintf('录音数据大小: %d x %d', size(app.recordingData, 1), size(app.recordingData, 2));

    % Save the recording as a WAV file
    filename = './data.wav';
    audiowrite(filename, Recording, Fs);
    app.EditField.Value = [app.EditField.Value, newline, '   音频已保存'];    
        end

        % Callback function
        function Menu_26Selected(app, event)
    if isempty(app.recordingData)
    uialert(app.UIFigure, '没有录音数据，请先录音！', '错误');
    return;
    end
    Recording = app.recordingData;
    Fs=app.sampleRate;
    leftChannel = Recording(:, 1);  % 左声道
    rightChannel = Recording(:, 2); % 右声道
    
    % 计算时间轴
    t = (0:length(Recording)-1) / Fs;

    % 在 UIAxes_2 上绘制左声道波形
    plot(app.UIAxes_2, t, leftChannel, 'b');  % 蓝色波形
    xlabel(app.UIAxes_2, '时间 (秒)');
    ylabel(app.UIAxes_2, '幅度');
    title(app.UIAxes_2, '左声道波形');
    grid(app.UIAxes_2, 'on');
    
    % 在 UIAxes 上绘制右声道波形
    plot(app.UIAxes, t, rightChannel, 'r');  % 红色波形
    xlabel(app.UIAxes, '时间 (秒)');
    ylabel(app.UIAxes, '幅度');
    title(app.UIAxes, '右声道波形');
    grid(app.UIAxes, 'on');
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
    Fs = 44100;               % Sampling frequency
    duration = 4;             % Duration of the recording
    nBits = 16;               % Bit depth
    nChannels = 2;            % Number of channels
    recObj = audiorecorder(Fs, nBits, nChannels); 
   
    % Inform the user that recording has started
    app.EditField.Value = '录音开始!';
    recordblocking(recObj, duration);  % Record the audio for the specified duration
    app.EditField.Value = '录音结束!'; 
    
    % Retrieve the recorded data
    Recording = getaudiodata(recObj);
    
    % Store the recording and sample rate in the app properties
    app.recordingData = Recording;
    app.sampleRate = Fs;  % 存储采样率
    %测试
    %app.EditField.Value = sprintf('录音数据大小: %d x %d', size(app.recordingData, 1), size(app.recordingData, 2));

    % Save the recording as a WAV file
    filename = './data.wav';
    audiowrite(filename, Recording, Fs);
    app.EditField.Value = [app.EditField.Value, newline, '   音频已保存'];
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
    if isempty(app.recordingData)
    uialert(app.UIFigure, '没有录音数据，请先录音！', '错误');
    return;
    end
    Recording = app.recordingData;
    Fs=app.sampleRate;
    leftChannel = Recording(:, 1);  % 左声道
    rightChannel = Recording(:, 2); % 右声道
    
    % 计算时间轴
    t = (0:length(Recording)-1) / Fs;

    % 在 UIAxes_2 上绘制左声道波形
    plot(app.UIAxes_2, t, leftChannel, 'b');  % 蓝色波形
    xlabel(app.UIAxes_2, '时间 (秒)');
    ylabel(app.UIAxes_2, '幅度');
    title(app.UIAxes_2, '左声道波形');
    grid(app.UIAxes_2, 'on');
    
    % 在 UIAxes 上绘制右声道波形
    plot(app.UIAxes, t, rightChannel, 'r');  % 红色波形
    xlabel(app.UIAxes, '时间 (秒)');
    ylabel(app.UIAxes, '幅度');
    title(app.UIAxes, '右声道波形');
    grid(app.UIAxes, 'on');
    
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            delete(app.UIFigure);
        end

        % Menu selected function: Menu_10
        function Menu_10Selected(app, event)
            processSignal(app, '放大');
        end

        % Menu selected function: Menu_12
        function Menu_12Selected(app, event)
          processSignal(app, '缩小');  
        end

        % Callback function
        function Menu_13Selected(app, event)
         % processSignal(app, '缩小左声道');  
        end

        % Callback function
        function Menu_14Selected(app, event)
         % processSignal(app, '缩小右声道');  
        end

        % Menu selected function: Menu_15
        function Menu_15Selected(app, event)
         processSignal(app, '加法');   
        end

        % Menu selected function: Menu_16
        function Menu_16Selected(app, event)
          processSignal(app, '减法');  
        end

        % Menu selected function: Menu_17
        function Menu_17Selected(app, event)
          processSignal(app, '乘法');  
        end

        % Menu selected function: Menu_18
        function Menu_18Selected(app, event)
          processSignal(app, '卷积');  
        end

        % Menu selected function: Menu_19
        function Menu_19Selected(app, event)
           processSignal(app, '相关'); 
        end

        % Menu selected function: Menu_21
        function Menu_21Selected(app, event)
            analyzeSpectrum(app, '左声道');
        end

        % Menu selected function: Menu_23
        function Menu_23Selected(app, event)
            analyzeSpectrum(app, '右声道');
        end

        % Menu selected function: Menu_24
        function Menu_24Selected(app, event)
            applyFilter(app, '左声道');
        end

        % Menu selected function: Menu_25
        function Menu_25Selected(app, event)
            applyFilter(app, '右声道');
        end

        % Menu selected function: Menu_4
        function Menu_4Selected(app, event)
            generateSignal(app, '正弦信号');
        end

        % Menu selected function: Menu_5
        function Menu_5Selected(app, event)
           generateSignal(app, '方波信号'); 
        end

        % Menu selected function: ChirpMenu
        function ChirpMenuSelected(app, event)
           generateSignal(app, 'Chirp信号'); 
        end

        % Menu selected function: Menu_6
        function Menu_6Selected(app, event)
           generateSignal(app, '阶跃信号'); 
        end

        % Menu selected function: Menu_7
        function Menu_7Selected(app, event)
          generateSignal(app, '抽样信号');  
        end

        % Menu selected function: Menu_8
        function Menu_8Selected(app, event)
           generateSignal(app, '冲激信号'); 
        end

        % Menu selected function: Menu_9
        function Menu_9Selected(app, event)
           generateSignal(app, '复指数信号'); 
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %时域波形分析按钮
            % 确保录音数据存在
    if isempty(app.recordingData) || size(app.recordingData, 2) < 2
        app.EditField.Value = '请先录音!';
        return;
    end
    
    % 获取信号选择状态
    selectedSignals = {};
    
    if app.CheckBox.Value
        selectedSignals{end+1} = 'left';
    end
    if app.CheckBox_2.Value
        selectedSignals{end+1} = 'right';
    end
    if app.ChirpCheckBox.Value
        selectedSignals{end+1} = 'Chirp';
    end
    if app.CheckBox_4.Value
        selectedSignals{end+1} = 'Sine';
    end
    if app.CheckBox_5.Value
        selectedSignals{end+1} = 'Step';
    end
    if app.CheckBox_6.Value
        selectedSignals{end+1} = 'Square';
    end
    if app.CheckBox_7.Value
        selectedSignals{end+1} = 'sampling';
    end
    if app.CheckBox_8.Value
        selectedSignals{end+1} = 'Impulse';
    end
    if app.CheckBox_9.Value
        selectedSignals{end+1} = 'Complexexponential';
    end
    
    % 确保选择了一个信号
    if numel(selectedSignals) ~= 1
        app.EditField.Value = '请选择一个信号!';
        return;
    end
    
    % 获取录音数据和采样率
    Fs = app.sampleRate;
    t = (0:length(app.recordingData)-1) / Fs;
    
    % 定义信号
    signals = struct();
    signals.('left') = app.recordingData(:, 1); % 左声道
    signals.('right') = app.recordingData(:, 2); % 右声道
    signals.('Sine') = sin(2*pi*t);         % 正弦信号
    signals.('Square') = square(2*pi*t);    % 方波信号
    signals.('Chirp') = chirp(t, 1, max(t), 5); % Chirp 信号
    signals.('Step') = (t >= mean(t));        % 阶跃信号 (将跳变点放在中间)

    t = linspace(0, 1, 1000);  % 时间范围 [0, 1]，1000 个点
    center = 0.5;  % 中心点位置
    % 生成 sinc 函数，中心在 t=0.5
    signals.('sampling') = sinc(10 * (t - center));

    % 冲激信号，0 在坐标轴中间
    impulse = zeros(size(t)); 
    impulse(round(length(t)/2)) = 1;  % 在中心位置放 1
    signals.('Impulse') = impulse;

    % 复指数信号
    tau = 0.2;  % 时间常数
    f = 5;  % 频率
    signals.('Complexexponential') = exp(-t / tau) .* exp(1j*2*pi*f*t);  % 复指数衰减信号
    
    % 获取用户选择的信号数据
    try
        signal = signals.(selectedSignals{1});
    catch
        app.EditField.Value = '所选信号无效!';
        return;
    end

    % 确保信号是实数
    if ~isreal(signal)
        signal = real(signal);
    end

    % 计算信号的有效值（RMS）
    rmsValue = sqrt(mean(signal.^2));
    
    % 计算信号的均值
    meanValue = mean(signal);
    
    % 计算信号的方差
    varianceValue = var(signal);
    
    % 计算信号的峰峰值（Peak-to-Peak）
    peakToPeakValue = max(signal) - min(signal);

    % 将结果显示在对应的 EditField 中
    app.EditField_2.Value = num2str(rmsValue); % 有效值
    app.EditField_3.Value = num2str(meanValue); % 均值
    app.EditField_4.Value = num2str(varianceValue); % 方差
    app.EditField_5.Value = num2str(peakToPeakValue); % 峰峰值

    % 更新状态信息
    app.EditField.Value = ['信号分析完成: ', selectedSignals{1}];

        end

        % Menu selected function: Menu_27
        function Menu_27Selected(app, event)
                selfmadefunc(app,'周期');
        end

        % Menu selected function: Menu_28
        function Menu_28Selected(app, event)
            selfmadefunc(app,'峰值');
        end

        % Menu selected function: Menu_29
        function Menu_29Selected(app, event)
            selfmadefunc(app,'峰峰值');
        end

        % Menu selected function: Menu_30
        function Menu_30Selected(app, event)
            selfmadefunc(app,'均值');
        end

        % Menu selected function: Menu_31
        function Menu_31Selected(app, event)
            selfmadefunc(app,'均方值');
        end

        % Menu selected function: RMSMenu
        function RMSMenuSelected(app, event)
            selfmadefunc(app,'RMS(均方根)');
        end

        % Menu selected function: Menu_32
        function Menu_32Selected(app, event)
            selfmadefunc(app,'方差');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = '常见信号库';
            app.Menu_4 = uimenu(app.Menu_3);
            app.Menu_4.MenuSelectedFcn = createCallbackFcn(app, @Menu_4Selected, true);
            app.Menu_4.Text = '正弦信号';
            app.Menu_5 = uimenu(app.Menu_3);
            app.Menu_5.MenuSelectedFcn = createCallbackFcn(app, @Menu_5Selected, true);
            app.Menu_5.Text = '方波信号';
            app.ChirpMenu = uimenu(app.Menu_3);
            app.ChirpMenu.MenuSelectedFcn = createCallbackFcn(app, @ChirpMenuSelected, true);
            app.ChirpMenu.Text = 'Chirp信号';
            app.Menu_6 = uimenu(app.Menu_3);
            app.Menu_6.MenuSelectedFcn = createCallbackFcn(app, @Menu_6Selected, true);
            app.Menu_6.Text = '阶跃信号';
            app.Menu_7 = uimenu(app.Menu_3);
            app.Menu_7.MenuSelectedFcn = createCallbackFcn(app, @Menu_7Selected, true);
            app.Menu_7.Text = '抽样信号';
            app.Menu_8 = uimenu(app.Menu_3);
            app.Menu_8.MenuSelectedFcn = createCallbackFcn(app, @Menu_8Selected, true);
            app.Menu_8.Text = '冲激信号';
            app.Menu_9 = uimenu(app.Menu_3);
            app.Menu_9.MenuSelectedFcn = createCallbackFcn(app, @Menu_9Selected, true);
            app.Menu_9.Text = '复指数信号';
            app.Menu_11 = uimenu(app.UIFigure);
            app.Menu_11.Text = '信号运算';
            app.Menu_10 = uimenu(app.Menu_11);
            app.Menu_10.MenuSelectedFcn = createCallbackFcn(app, @Menu_10Selected, true);
            app.Menu_10.Text = '放大';
            app.Menu_12 = uimenu(app.Menu_11);
            app.Menu_12.MenuSelectedFcn = createCallbackFcn(app, @Menu_12Selected, true);
            app.Menu_12.Text = '缩小';
            app.Menu_15 = uimenu(app.Menu_11);
            app.Menu_15.MenuSelectedFcn = createCallbackFcn(app, @Menu_15Selected, true);
            app.Menu_15.Text = '加法';
            app.Menu_16 = uimenu(app.Menu_11);
            app.Menu_16.MenuSelectedFcn = createCallbackFcn(app, @Menu_16Selected, true);
            app.Menu_16.Text = '减法';
            app.Menu_17 = uimenu(app.Menu_11);
            app.Menu_17.MenuSelectedFcn = createCallbackFcn(app, @Menu_17Selected, true);
            app.Menu_17.Text = '乘法';
            app.Menu_18 = uimenu(app.Menu_11);
            app.Menu_18.MenuSelectedFcn = createCallbackFcn(app, @Menu_18Selected, true);
            app.Menu_18.Text = '卷积(对常见信号)';
            app.Menu_19 = uimenu(app.Menu_11);
            app.Menu_19.MenuSelectedFcn = createCallbackFcn(app, @Menu_19Selected, true);
            app.Menu_19.Text = '相关(对常见信号)';
            app.Menu_26 = uimenu(app.UIFigure);
            app.Menu_26.Text = '自设计算法';
            app.Menu_27 = uimenu(app.Menu_26);
            app.Menu_27.MenuSelectedFcn = createCallbackFcn(app, @Menu_27Selected, true);
            app.Menu_27.Text = '周期';
            app.Menu_28 = uimenu(app.Menu_26);
            app.Menu_28.MenuSelectedFcn = createCallbackFcn(app, @Menu_28Selected, true);
            app.Menu_28.Text = '峰值';
            app.Menu_29 = uimenu(app.Menu_26);
            app.Menu_29.MenuSelectedFcn = createCallbackFcn(app, @Menu_29Selected, true);
            app.Menu_29.Text = '峰峰值';
            app.Menu_30 = uimenu(app.Menu_26);
            app.Menu_30.MenuSelectedFcn = createCallbackFcn(app, @Menu_30Selected, true);
            app.Menu_30.Text = '均值';
            app.Menu_31 = uimenu(app.Menu_26);
            app.Menu_31.MenuSelectedFcn = createCallbackFcn(app, @Menu_31Selected, true);
            app.Menu_31.Text = '均方值';
            app.RMSMenu = uimenu(app.Menu_26);
            app.RMSMenu.MenuSelectedFcn = createCallbackFcn(app, @RMSMenuSelected, true);
            app.RMSMenu.Text = 'RMS(均方根)';
            app.Menu_32 = uimenu(app.Menu_26);
            app.Menu_32.MenuSelectedFcn = createCallbackFcn(app, @Menu_32Selected, true);
            app.Menu_32.Text = '方差';
            app.Menu_20 = uimenu(app.UIFigure);
            app.Menu_20.Text = '频谱分析';
            app.Menu_21 = uimenu(app.Menu_20);
            app.Menu_21.MenuSelectedFcn = createCallbackFcn(app, @Menu_21Selected, true);
            app.Menu_21.Text = '左声道';
            app.Menu_23 = uimenu(app.Menu_20);
            app.Menu_23.MenuSelectedFcn = createCallbackFcn(app, @Menu_23Selected, true);
            app.Menu_23.Text = '右声道';
            app.Menu_22 = uimenu(app.UIFigure);
            app.Menu_22.Text = '滤波器';
            app.Menu_24 = uimenu(app.Menu_22);
            app.Menu_24.MenuSelectedFcn = createCallbackFcn(app, @Menu_24Selected, true);
            app.Menu_24.Text = '左声道';
            app.Menu_25 = uimenu(app.Menu_22);
            app.Menu_25.MenuSelectedFcn = createCallbackFcn(app, @Menu_25Selected, true);
            app.Menu_25.Text = '右声道';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '屏幕2')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '屏幕1')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.MATLABLabel = uilabel(app.UIFigure);
            app.MATLABLabel.Text = 'MATLAB信号分析仪';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '制作人：贺诗璐';
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '进度区';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '显示';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '录音';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '退出';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '信号选择栏';
            app.CheckBox = uicheckbox(app.Panel);
            app.CheckBox.Text = '左声道';
            app.CheckBox_2 = uicheckbox(app.Panel);
            app.CheckBox_2.Text = '右声道';
            app.ChirpCheckBox = uicheckbox(app.Panel);
            app.ChirpCheckBox.Text = 'Chirp信号';
            app.CheckBox_4 = uicheckbox(app.Panel);
            app.CheckBox_4.Text = '正弦信号';
            app.CheckBox_5 = uicheckbox(app.Panel);
            app.CheckBox_5.Text = '阶跃信号';
            app.CheckBox_6 = uicheckbox(app.Panel);
            app.CheckBox_6.Text = '方波信号';
            app.CheckBox_7 = uicheckbox(app.Panel);
            app.CheckBox_7.Text = '抽样信号';
            app.CheckBox_8 = uicheckbox(app.Panel);
            app.CheckBox_8.Text = '冲激信号';
            app.CheckBox_9 = uicheckbox(app.Panel);
            app.CheckBox_9.Text = '复指数信号';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '时域波形分析';
            app.Panel_2 = uipanel(app.UIFigure);
            app.Panel_2.Title = '信号波形分析展示';
            app.Label_2 = uilabel(app.Panel_2);
            app.Label_2.Text = '有效值';
            app.EditField_2 = uieditfield(app.Panel_2, 'text');
            app.Label_3 = uilabel(app.Panel_2);
            app.Label_3.Text = '均值';
            app.EditField_3 = uieditfield(app.Panel_2, 'text');
            app.Label_4 = uilabel(app.Panel_2);
            app.Label_4.Text = '方差';
            app.EditField_4 = uieditfield(app.Panel_2, 'text');
            app.Label_5 = uilabel(app.Panel_2);
            app.Label_5.Text = '峰峰值';
            app.EditField_5 = uieditfield(app.Panel_2, 'text');
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '信号特征值';
            app.EditField_6 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end